classdef Air_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function test(app)
           hMod = comm.OFDMModulator;
           hMod.FFTLength = 128;
           hMod.NumSymbols = 2;
           
           disp(hMod)
           
           showResourceMapping(hMod)
        end
        
        function test2(app)
            %sine = dsp.SineWave(amp,freq,phase,Name,Value)
            sine1 = dsp.SineWave();
            
            sine1.Frequency = app.EditFrequency.Value;
            sine1.PhaseOffset = [0 pi/2];
            sine1.SamplesPerFrame = 1000;
            y = sine1();
            
            plot(app.UIAxes , y );


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.test2()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Air_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end